classdef equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioEqualizerUIFigure       matlab.ui.Figure
        FileLoaderPanel              matlab.ui.container.Panel
        FilePathLabel                matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        BrowseButton                 matlab.ui.control.Button
        EqualizerPanel               matlab.ui.container.Panel
        OutputSampleRateHzLabel      matlab.ui.control.Label
        OutputSampleRateHzEditField  matlab.ui.control.NumericEditField
        ButtonGroup                  matlab.ui.container.ButtonGroup
        FIRButton                    matlab.ui.control.RadioButton
        IIRButton                    matlab.ui.control.RadioButton
        FilterTypeLabel              matlab.ui.control.Label
        HzSliderLabel                matlab.ui.control.Label
        Band1Slider                  matlab.ui.control.Slider
        Band1EditField               matlab.ui.control.NumericEditField
        Band2Label                   matlab.ui.control.Label
        Band2Slider                  matlab.ui.control.Slider
        Band2EditField               matlab.ui.control.NumericEditField
        Band3Label                   matlab.ui.control.Label
        Band3Slider                  matlab.ui.control.Slider
        Band3EditField               matlab.ui.control.NumericEditField
        Band4EditField               matlab.ui.control.NumericEditField
        Band5Label                   matlab.ui.control.Label
        Band5Slider                  matlab.ui.control.Slider
        Band5EditField               matlab.ui.control.NumericEditField
        Band6Label                   matlab.ui.control.Label
        Band6Slider                  matlab.ui.control.Slider
        Band6EditField               matlab.ui.control.NumericEditField
        HzLabel                      matlab.ui.control.Label
        Band4Slider                  matlab.ui.control.Slider
        Band7EditField               matlab.ui.control.NumericEditField
        Band8Label                   matlab.ui.control.Label
        Band8Slider                  matlab.ui.control.Slider
        Band8EditField               matlab.ui.control.NumericEditField
        Band9Label                   matlab.ui.control.Label
        Band9Slider                  matlab.ui.control.Slider
        Band9EditField               matlab.ui.control.NumericEditField
        KHzSliderLabel               matlab.ui.control.Label
        Band7Slider                  matlab.ui.control.Slider
        FilterButton                 matlab.ui.control.Button
        AudioPlayerPanel             matlab.ui.container.Panel
        PlayButton                   matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        ResumeButton                 matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        VisualizerPanel              matlab.ui.container.Panel
        OriginalUIAxes               matlab.ui.control.UIAxes
        FilteredUIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        x % input signal
        inFs % input sample frequency
        outFs = 48000% ouput sample frequency (set by user)
        mode = 1; %IRR or FIR -- IRR = 0, FIR = 1
        gain = [1 1 1 1 1 1 1 1 1];
        y1, y2, y3, y4, y5, y6, y7, y8, y9;    % output signals
        yt; % composite output signal
        player; % audio player
    end

    methods (Access = public)
        function Hds = FIRs(app, Fs)
            Hds = [dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir];
            N    = 16;       % Order
            Fc1  = 0.1;      % First Cutoff Frequency
            Fc2  = 170;      % Second Cutoff Frequency
            flag = 'scale';  % Sampling Flag
            Beta = 0.5;      % Window Parameter
            % Create the window vector for the design algorithm.
            win = kaiser(N+1, Beta);
            
            % Calculate the coefficients using the FIR1 function.
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(1) = dfilt.dffir(b);
            Fc1 = 170;
            Fc2 = 310;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(2) = dfilt.dffir(b);
            Fc1 = 310;
            Fc2 = 600;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(3) = dfilt.dffir(b);
            Fc1 = 600;
            Fc2 = 1000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(4) = dfilt.dffir(b);
            Fc1 = 1000;
            Fc2 = 3000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(5) = dfilt.dffir(b);
            Fc1 = 3000;
            Fc2 = 6000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(6) = dfilt.dffir(b);
            Fc1 = 6000;
            Fc2 = 12000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(7) = dfilt.dffir(b);
            Fc1 = 12000;
            Fc2 = 14000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(8) = dfilt.dffir(b);
            Fc1 = 14000;
            Fc2 = 16000;
            b  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            Hds(9) = dfilt.dffir(b);
        end
        
        function Hds = IIRs(app, Fs)
            Hds = [dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir];
            N   = 16;   % Order
            Fc1 = 0.1;  % First Cutoff Frequency
            Fc2 = 170;  % Second Cutoff Frequency
            
            % Construct an FDESIGN object and call its BUTTER method.
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(1) = design(h, 'butter');
            
            Fc1 = 170;
            Fc2 = 310;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(2) = design(h, 'butter');
            
            Fc1 = 310;
            Fc2 = 600;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(3) = design(h, 'butter');
            
            Fc1 = 600;
            Fc2 = 1000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(4) = design(h, 'butter');
            
            Fc1 = 1000;
            Fc2 = 3000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(5) = design(h, 'butter');
            
            Fc1 = 3000;
            Fc2 = 6000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(6) = design(h, 'butter');
            
            Fc1 = 6000;
            Fc2 = 12000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(7) = design(h, 'butter');
            
            Fc1 = 12000;
            Fc2 = 14000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(8) = design(h, 'butter');
            
            Fc1 = 14000;
            Fc2 = 16000;
            h  = fdesign.bandpass('N,F3dB1,F3dB2', N, Fc1, Fc2, Fs);
            Hds(9) = design(h, 'butter');
        end
    end


    methods (Access = private)

        % Value changed function: Band1EditField
        function Band1EditFieldValueChanged(app, event)
            value = app.Band1EditField.Value;
            app.Band1Slider.Value = value;
        end

        % Value changing function: Band1Slider
        function Band1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band1EditField.Value = changingValue;
        end

        % Value changed function: Band2EditField
        function Band2EditFieldValueChanged(app, event)
            value = app.Band2EditField.Value;
            app.Band2Slider.Value = value;
        end

        % Value changing function: Band2Slider
        function Band2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band2EditField.Value = changingValue;
        end

        % Value changed function: Band3EditField
        function Band3EditFieldValueChanged(app, event)
            value = app.Band3EditField.Value;
            app.Band3Slider.Value = value;
        end

        % Value changing function: Band3Slider
        function Band3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band3EditField.Value = changingValue;
        end

        % Value changed function: Band4EditField
        function Band4EditFieldValueChanged(app, event)
            value = app.Band4EditField.Value;
            app.Band4Slider.Value = value;
        end

        % Value changing function: Band4Slider
        function Band4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band4EditField.Value = changingValue;
        end

        % Value changed function: Band5EditField
        function Band5EditFieldValueChanged(app, event)
            value = app.Band5EditField.Value;
            app.Band5Slider.Value = value;
        end

        % Value changing function: Band5Slider
        function Band5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band5EditField.Value = changingValue;
        end

        % Value changed function: Band6EditField
        function Band6EditFieldValueChanged(app, event)
            value = app.Band6EditField.Value;
            app.Band6Slider.Value = value;
        end

        % Value changing function: Band6Slider
        function Band6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band6EditField.Value = changingValue;
        end

        % Value changed function: Band7EditField
        function Band7EditFieldValueChanged(app, event)
            value = app.Band7EditField.Value;
            app.Band7Slider.Value = value;
        end

        % Value changing function: Band7Slider
        function Band7SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band7EditField.Value = changingValue;
        end

        % Value changed function: Band8EditField
        function Band8EditFieldValueChanged(app, event)
            value = app.Band8EditField.Value;
            app.Band8Slider.Value = value;
        end

        % Value changing function: Band8Slider
        function Band8SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band8EditField.Value = changingValue;
        end

        % Value changed function: Band9EditField
        function Band9EditFieldValueChanged(app, event)
            value = app.Band9EditField.Value;
            app.Band9Slider.Value = value;
        end

        % Value changing function: Band9Slider
        function Band9SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Band9EditField.Value = changingValue;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.wav','Select a File');
            if isequal(file,0)
               % uialert(app.AudioEqualizerUIFigure,'File not found','Invalid File','Icon','warning');
            else
               filePath = fullfile(path,file);
               app.FilePathEditField.Value = filePath;
               [app.x,app.inFs] = audioread(filePath);
               plot(app.OriginalUIAxes, app.x);
               app.yt = app.x;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.mode = app.FIRButton.Value;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            % TODO: Plot input signal in time and frequency domain %
            % Recommended: show the plot in originalAxis in the GUI %
            % -- code should be here -- %
            
            t = linspace(0,length(app.x)/app.inFs,length(app.x));
            Nfft = 16000;
            %Nfft = length(app.x);
            %G = fft(app.x,Nfft);
            %f = ((0:1/Nfft:1-1/Nfft)*app.inFs).';
            f = linspace(0,app.inFs,Nfft);
            G = abs(fft(app.x,Nfft));
            %magnitudeY = abs(app.x);        % Magnitude of the FFT
            %phaseY = unwrap(angle(app.x));  % Phase of the FFT

            %helperFrequencyAnalysisPlot1(f,magnitudeY,phaseY,Nfft)
            
            figure();
            subplot(2,1,1)
            plot(t, app.x);
            title('Time domain')
            
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')


            % Get user gains %
            app.gain(1) = app.Band1EditField.Value;
            app.gain(2) = app.Band2EditField.Value;
            app.gain(3) = app.Band3EditField.Value;
            app.gain(4) = app.Band4EditField.Value;
            app.gain(5) = app.Band5EditField.Value;
            app.gain(6) = app.Band6EditField.Value;
            app.gain(7) = app.Band7EditField.Value;
            app.gain(8) = app.Band8EditField.Value;
            app.gain(9) = app.Band9EditField.Value;
            
            % Design filters %
            Hds = [dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir dfilt.dffir];
            if isequal(app.mode,1)
                Hds = FIRs(app, app.outFs);
            else
                Hds = IIRs(app, app.outFs);
            end
            
            % TODO: Plot all filters and their attributes %
            % -- code should be here -- %
            for i = 1:9
                fvtool(Hds(i));
            end


            app.y1 = filter(Hds(1), app.x);
            app.y2 = filter(Hds(2), app.x);
            app.y3 = filter(Hds(3), app.x);
            app.y4 = filter(Hds(4), app.x);
            app.y5 = filter(Hds(5), app.x);
            app.y6 = filter(Hds(6), app.x);
            app.y7 = filter(Hds(7), app.x);
            app.y8 = filter(Hds(8), app.x);
            app.y9 = filter(Hds(9), app.x);
            
            % TODO: Plot output signals in time and frequency domain before amplification %
            % Recommended: save all plots in the current directory %
            % -- code should be here -- %
            
            t = linspace(0,length(app.y1)/app.inFs,length(app.y1));
            G = abs(fft(app.y1,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y1);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y2)/app.inFs,length(app.y2));
            G = abs(fft(app.y2,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y2);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y3)/app.inFs,length(app.y3));
            G = abs(fft(app.y3,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y3);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y4)/app.inFs,length(app.y4));
            G = abs(fft(app.y4,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y4);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y5)/app.inFs,length(app.y5));
            G = abs(fft(app.y5,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y5);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y6)/app.inFs,length(app.y6));
            G = abs(fft(app.y6,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y6);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y7)/app.inFs,length(app.y7));
            G = abs(fft(app.y7,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y7);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y8)/app.inFs,length(app.y8));
            G = abs(fft(app.y8,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y8);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
            
            t = linspace(0,length(app.y9)/app.inFs,length(app.y9));
            G = abs(fft(app.y9,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.y9);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')


            app.y1 = app.y1 * app.gain(1);
            app.y2 = app.y2 * app.gain(2);
            app.y3 = app.y3 * app.gain(3);
            app.y4 = app.y4 * app.gain(4);
            app.y5 = app.y5 * app.gain(5);
            app.y6 = app.y6 * app.gain(6);
            app.y7 = app.y7 * app.gain(7);
            app.y8 = app.y8 * app.gain(8);
            app.y9 = app.y9 * app.gain(9);
            
            app.yt = app.y1 + app.y2 + app.y3 + app.y4 + app.y5 + app.y6 + app.y7 + app.y8 + app.y9;
            
            % TODO: plot output composite signal in time and frequency domain %
            % Recommended: show the plot in filteredAxis in the GUI %
            % -- code should be here -- %
            t = linspace(0,length(app.yt)/app.inFs,length(app.yt));
            G = abs(fft(app.yt,Nfft));
            figure();
            subplot(2,1,1)
            plot(t, app.yt);
            title('Time domain')
            subplot(2,1,2);
            plot(f(1:Nfft/2),G(1:Nfft/2));
            title('Frequency domain')
        end

        % Value changed function: OutputSampleRateHzEditField
        function OutputSampleRateHzEditFieldValueChanged(app, event)
            value = app.OutputSampleRateHzEditField.Value;
            app.outFs = value;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.player = audioplayer(app.yt, app.outFs);
            play(app.player);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite('output.wav',app.yt,app.outFs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioEqualizerUIFigure = uifigure;
            app.AudioEqualizerUIFigure.Name = 'Audio Equalizer';
            app.FileLoaderPanel = uipanel(app.AudioEqualizerUIFigure);
            app.FileLoaderPanel.Title = 'File Loader';
            app.FilePathLabel = uilabel(app.FileLoaderPanel);
            app.FilePathLabel.Text = 'File Path:';
            app.FilePathEditField = uieditfield(app.FileLoaderPanel, 'text');
            app.BrowseButton = uibutton(app.FileLoaderPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse..';
            app.EqualizerPanel = uipanel(app.AudioEqualizerUIFigure);
            app.EqualizerPanel.Title = 'Equalizer';
            app.OutputSampleRateHzLabel = uilabel(app.EqualizerPanel);
            app.OutputSampleRateHzLabel.Text = 'Output Sample Rate (Hz):';
            app.OutputSampleRateHzEditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.OutputSampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @OutputSampleRateHzEditFieldValueChanged, true);
            app.OutputSampleRateHzEditField.ValueDisplayFormat = '%.0f';
            app.OutputSampleRateHzEditField.Value = 48000;
            app.ButtonGroup = uibuttongroup(app.EqualizerPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FIRButton = uiradiobutton(app.ButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.ButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.FilterTypeLabel = uilabel(app.EqualizerPanel);
            app.FilterTypeLabel.Text = 'Filter Type:';
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '0 - 170 Hz';
            app.Band1Slider = uislider(app.EqualizerPanel);
            app.Band1Slider.Limits = [-20 20];
            app.Band1Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band1Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band1Slider.ValueChangingFcn = createCallbackFcn(app, @Band1SliderValueChanging, true);
            app.Band1Slider.MinorTicks = [];
            app.Band1EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band1EditField.ValueChangedFcn = createCallbackFcn(app, @Band1EditFieldValueChanged, true);
            app.Band1EditField.Limits = [-20 20];
            app.Band1EditField.ValueDisplayFormat = '%11.3g';
            app.Band2Label = uilabel(app.EqualizerPanel);
            app.Band2Label.Text = '170 - 310 Hz';
            app.Band2Slider = uislider(app.EqualizerPanel);
            app.Band2Slider.Limits = [-20 20];
            app.Band2Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band2Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band2Slider.ValueChangingFcn = createCallbackFcn(app, @Band2SliderValueChanging, true);
            app.Band2Slider.MinorTicks = [];
            app.Band2EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band2EditField.ValueChangedFcn = createCallbackFcn(app, @Band2EditFieldValueChanged, true);
            app.Band3Label = uilabel(app.EqualizerPanel);
            app.Band3Label.Text = '310 - 600 Hz';
            app.Band3Slider = uislider(app.EqualizerPanel);
            app.Band3Slider.Limits = [-20 20];
            app.Band3Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band3Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band3Slider.ValueChangingFcn = createCallbackFcn(app, @Band3SliderValueChanging, true);
            app.Band3Slider.MinorTicks = [];
            app.Band3EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band3EditField.ValueChangedFcn = createCallbackFcn(app, @Band3EditFieldValueChanged, true);
            app.Band4EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band4EditField.ValueChangedFcn = createCallbackFcn(app, @Band4EditFieldValueChanged, true);
            app.Band5Label = uilabel(app.EqualizerPanel);
            app.Band5Label.Text = '1 - 3 KHz';
            app.Band5Slider = uislider(app.EqualizerPanel);
            app.Band5Slider.Limits = [-20 20];
            app.Band5Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band5Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band5Slider.ValueChangingFcn = createCallbackFcn(app, @Band5SliderValueChanging, true);
            app.Band5Slider.MinorTicks = [];
            app.Band5EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band5EditField.ValueChangedFcn = createCallbackFcn(app, @Band5EditFieldValueChanged, true);
            app.Band6Label = uilabel(app.EqualizerPanel);
            app.Band6Label.Text = '3 - 6 KHz';
            app.Band6Slider = uislider(app.EqualizerPanel);
            app.Band6Slider.Limits = [-20 20];
            app.Band6Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band6Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band6Slider.ValueChangingFcn = createCallbackFcn(app, @Band6SliderValueChanging, true);
            app.Band6Slider.MinorTicks = [];
            app.Band6EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band6EditField.ValueChangedFcn = createCallbackFcn(app, @Band6EditFieldValueChanged, true);
            app.HzLabel = uilabel(app.EqualizerPanel);
            app.HzLabel.Text = '600 - 1000 Hz';
            app.Band4Slider = uislider(app.EqualizerPanel);
            app.Band4Slider.Limits = [-20 20];
            app.Band4Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band4Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band4Slider.ValueChangingFcn = createCallbackFcn(app, @Band4SliderValueChanging, true);
            app.Band4Slider.MinorTicks = [];
            app.Band7EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band7EditField.ValueChangedFcn = createCallbackFcn(app, @Band7EditFieldValueChanged, true);
            app.Band8Label = uilabel(app.EqualizerPanel);
            app.Band8Label.Text = '12 - 14 KHz';
            app.Band8Slider = uislider(app.EqualizerPanel);
            app.Band8Slider.Limits = [-20 20];
            app.Band8Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band8Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band8Slider.ValueChangingFcn = createCallbackFcn(app, @Band8SliderValueChanging, true);
            app.Band8Slider.MinorTicks = [];
            app.Band8EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band8EditField.ValueChangedFcn = createCallbackFcn(app, @Band8EditFieldValueChanged, true);
            app.Band9Label = uilabel(app.EqualizerPanel);
            app.Band9Label.Text = '14 - 16 KHz';
            app.Band9Slider = uislider(app.EqualizerPanel);
            app.Band9Slider.Limits = [-20 20];
            app.Band9Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band9Slider.ValueChangingFcn = createCallbackFcn(app, @Band9SliderValueChanging, true);
            app.Band9Slider.MinorTicks = [];
            app.Band9EditField = uieditfield(app.EqualizerPanel, 'numeric');
            app.Band9EditField.ValueChangedFcn = createCallbackFcn(app, @Band9EditFieldValueChanged, true);
            app.KHzSliderLabel = uilabel(app.EqualizerPanel);
            app.KHzSliderLabel.Text = '6 - 12 KHz';
            app.Band7Slider = uislider(app.EqualizerPanel);
            app.Band7Slider.Limits = [-20 20];
            app.Band7Slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Band7Slider.MajorTickLabels = {'-20 db', '-15', '-10', '-5', '0', '5', '10', '15', '20 db'};
            app.Band7Slider.ValueChangingFcn = createCallbackFcn(app, @Band7SliderValueChanging, true);
            app.Band7Slider.MinorTicks = [];
            app.FilterButton = uibutton(app.EqualizerPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.AudioPlayerPanel = uipanel(app.AudioEqualizerUIFigure);
            app.AudioPlayerPanel.Title = 'Audio Player';
            app.PlayButton = uibutton(app.AudioPlayerPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.AudioPlayerPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.AudioPlayerPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.AudioPlayerPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.AudioPlayerPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save..';
            app.VisualizerPanel = uipanel(app.AudioEqualizerUIFigure);
            app.VisualizerPanel.Title = 'Visualizer';
            app.OriginalUIAxes = uiaxes(app.VisualizerPanel);
            title(app.OriginalUIAxes, 'Original')
            xlabel(app.OriginalUIAxes, 'X')
            ylabel(app.OriginalUIAxes, 'Y')
            app.FilteredUIAxes = uiaxes(app.VisualizerPanel);
            title(app.FilteredUIAxes, 'Filtered')
            xlabel(app.FilteredUIAxes, 'X')
            ylabel(app.FilteredUIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = equalizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioEqualizerUIFigure)
        end
    end
end